classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ApplyButton                     matlab.ui.control.Button
        RestorationMethodDropDown       matlab.ui.control.DropDown
        RestorationMethodDropDownLabel  matlab.ui.control.Label
        SpecInputFileEditField          matlab.ui.control.EditField
        InputSpecificationImageLabel    matlab.ui.control.Label
        InputFileEditField              matlab.ui.control.EditField
        InputFileEditFieldLabel         matlab.ui.control.Label
        BlueChannelCheckBox             matlab.ui.control.CheckBox
        GreenChannelCheckBox            matlab.ui.control.CheckBox
        RedChannelCheckBox              matlab.ui.control.CheckBox
        InputImage                      matlab.ui.control.Image
        SpecImage                       matlab.ui.control.Image
        OutputImage                     matlab.ui.control.UIAxes
        SpecHistAxes                    matlab.ui.control.UIAxes
        OutputHistAxes                  matlab.ui.control.UIAxes
        InputHistAxes                   matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        InputImage_val
        OutputImage_val
        SpecImage_val
    end
    
    methods (Access = private)
        
        function SetHistogram(app, axes, img)
            hist_data = myHist(img);
            [L, num_ch] = size(hist_data);
            color_values = 0:(L - 1);
            cla(axes)
            hold(axes, "on");
            for c = 1:num_ch
                if IsChannelDisplayed(app, c)
                    color_name = GetBarColor(app, c);
                    bar(axes, color_values, hist_data(:, c), color_name, color_name);
                end 
            end
            hold(axes, "off");
        end
        
        function color_name = GetBarColor(~, idx)
            if idx == 1
                color_name = 'red';
            elseif idx == 2
                color_name = 'green';
            else
                color_name = 'blue';
            end
        end
        
        function displayed = IsChannelDisplayed(app, idx)
            if idx == 1
                displayed = app.RedChannelCheckBox.Value;
            elseif idx == 2
                displayed = app.GreenChannelCheckBox.Value;
            else
                displayed = app.BlueChannelCheckBox.Value;
            end
        end
        
        function ChangeWindowWidth(app, newWidth)
            app.UIFigure.Position = [100 100 newWidth 640];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InputFileEditField
        function InputFileEditFieldValueChanged(app, event)
            value = app.InputFileEditField.Value;
            app.InputImage.ImageSource = value;
            app.InputImage_val = imread(value);
            SetHistogram(app, app.InputHistAxes, app.InputImage_val);
        end

        % Value changed function: BlueChannelCheckBox, 
        % ...and 2 other components
        function ResetHistogram(app, event)
            SetHistogram(app, app.InputHistAxes, app.InputImage_val);
            SetHistogram(app, app.OutputHistAxes, app.OutputImage_val);
        end

        % Value changed function: RestorationMethodDropDown
        function RestorationMethodDropDownValueChanged(app, event)
            value = app.RestorationMethodDropDown.Value;
            if strcmp('Histogram Specification', value)
                ChangeWindowWidth(app, 1280);
            else
                ChangeWindowWidth(app, 760);
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            method = app.RestorationMethodDropDown.Value;
            if strcmp('Histogram Equalization', method)
                app.OutputImage_val = histEqualize(app.InputImage_val);
            elseif strcmp('Histogram Specification', method)
                app.OutputImage_val = histSpecify(app.InputImage_val, app.SpecImage_val);
            end
            imshow(app.OutputImage_val, 'Parent', app.OutputImage);
            app.OutputImage.XLim = [0, size(app.OutputImage_val, 2)];
            app.OutputImage.YLim = [0, size(app.OutputImage_val, 1)];
            SetHistogram(app, app.OutputHistAxes, app.OutputImage_val);
        end

        % Value changed function: SpecInputFileEditField
        function SpecInputFileEditFieldValueChanged(app, event)
            value = app.SpecInputFileEditField.Value;
            app.SpecImage.ImageSource = value;
            app.SpecImage_val = imread(value);
            SetHistogram(app, app.SpecHistAxes, app.SpecImage_val);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.InputHistAxes = uiaxes(app.UIFigure);
            title(app.InputHistAxes, 'Input Image Histogram')
            xlabel(app.InputHistAxes, 'X')
            ylabel(app.InputHistAxes, 'Y')
            zlabel(app.InputHistAxes, 'Z')
            app.OutputHistAxes = uiaxes(app.UIFigure);
            title(app.OutputHistAxes, 'Output Image Histogram')
            xlabel(app.OutputHistAxes, 'X')
            ylabel(app.OutputHistAxes, 'Y')
            zlabel(app.OutputHistAxes, 'Z')
            app.SpecHistAxes = uiaxes(app.UIFigure);
            title(app.SpecHistAxes, 'Specification Image Histogram')
            xlabel(app.SpecHistAxes, 'X')
            ylabel(app.SpecHistAxes, 'Y')
            zlabel(app.SpecHistAxes, 'Z')
            app.OutputImage = uiaxes(app.UIFigure);
            app.OutputImage.XTick = [];
            app.OutputImage.YTick = [];
            app.OutputImage.ZTick = [];
            app.SpecImage = uiimage(app.UIFigure);
            app.InputImage = uiimage(app.UIFigure);
            app.RedChannelCheckBox = uicheckbox(app.UIFigure);
            app.RedChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.RedChannelCheckBox.Text = 'Red Channel';
            app.RedChannelCheckBox.Value = true;
            app.GreenChannelCheckBox = uicheckbox(app.UIFigure);
            app.GreenChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.GreenChannelCheckBox.Text = 'Green Channel';
            app.GreenChannelCheckBox.Value = true;
            app.BlueChannelCheckBox = uicheckbox(app.UIFigure);
            app.BlueChannelCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResetHistogram, true);
            app.BlueChannelCheckBox.Text = 'BlueChannel';
            app.BlueChannelCheckBox.Value = true;
            app.InputFileEditFieldLabel = uilabel(app.UIFigure);
            app.InputFileEditFieldLabel.Text = 'Input File';
            app.InputFileEditField = uieditfield(app.UIFigure, 'text');
            app.InputFileEditField.ValueChangedFcn = createCallbackFcn(app, @InputFileEditFieldValueChanged, true);
            app.InputSpecificationImageLabel = uilabel(app.UIFigure);
            app.InputSpecificationImageLabel.Text = 'Input Specification Image';
            app.SpecInputFileEditField = uieditfield(app.UIFigure, 'text');
            app.SpecInputFileEditField.ValueChangedFcn = createCallbackFcn(app, @SpecInputFileEditFieldValueChanged, true);
            app.RestorationMethodDropDownLabel = uilabel(app.UIFigure);
            app.RestorationMethodDropDownLabel.Text = 'Restoration Method';
            app.RestorationMethodDropDown = uidropdown(app.UIFigure);
            app.RestorationMethodDropDown.Items = {'Histogram Equalization', 'Histogram Specification'};
            app.RestorationMethodDropDown.ValueChangedFcn = createCallbackFcn(app, @RestorationMethodDropDownValueChanged, true);
            app.RestorationMethodDropDown.Value = 'Histogram Equalization';
            app.ApplyButton = uibutton(app.UIFigure, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end